classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RespirationExtractorUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadCSVMenu                   matlab.ui.container.Menu
        ExportCSVMenu                 matlab.ui.container.Menu
        RawECGAlphaSliderLabel_2      matlab.ui.control.Label
        RawECGAlphaSlider             matlab.ui.control.Slider
        RawECGAlphaSliderLabel        matlab.ui.control.Label
        LowerEditField                matlab.ui.control.NumericEditField
        LowerEditFieldLabel           matlab.ui.control.Label
        UpperEditField                matlab.ui.control.NumericEditField
        UpperEditFieldLabel           matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ECG
        Fs

        frequencyLimits % min and max of app.frequenices
        compositeTransform % Composite transform from iCWT
        frequencies % detected frequencies from CWT
        cwtCoeffs % Coefficients from CWT

        bounds
        COMPOSITE_OFFSET = 0;
        %% Plot objects 
        CompositePlot
        RawPlot
    end
    
    methods (Access = private)
        
        function LoadECG(app)
            % Get the filepath
            [file,path] = uigetfile("*.csv");
            % Read the ECG
            if file
                try
                    % Read
                    app.ECG = readmatrix(string(path) + string(file));
                    % Estimate the sampling rate 
                    app.Fs = 1/mean(diff(app.ECG(:,1)));
                    
                    % Normalize the ECG to between zero and one 
                    % app.ECG(:,2) = (app.ECG(:,2) - min(app.ECG(:,2))) / (max(app.ECG(:,2)) - min(app.ECG(:,2)));
                    app.ECG(:,2) = app.ECG(:,2) - mean(app.ECG(:,2));
    
                    % Calculate the CWT and iCWT
                    app.calculateCWT();
                    app.calculate_iCWT();
    
                    % Plot the raw data and the iCWT reconstruction
                    app.plotData();
    
                    % Change the slider to the appropriate bounds and position
                    app.Slider.Limits = [min(app.ECG(:,2)) max(app.ECG(:,2))];
                    app.Slider.Value = sum(app.Slider.Limits) / 2;


                    %% Enable stuff
                    app.Slider.Enable = 'on';
                    app.UpperEditField.Enable = 'on';
                    app.LowerEditField.Enable = 'on';
                    app.RawECGAlphaSlider.Enable = 'on';
                catch ME
                    uialert(app.RespirationExtractorUIFigure,"An error occurred. Make sure the data is formatted correctly, time in the first column, ecg in the second!","Error!")
                end
            end
        end
        
    
        function plotData(app)
            app.RawPlot = plot(app.UIAxes,app.ECG(:,1),app.ECG(:,2),'LineWidth',3,'Color',[0 0.3 1 .3]);
            app.CompositePlot = plot(app.UIAxes,app.ECG(:,1),app.compositeTransform,'Color',[.8 .4 .1],'LineWidth',3);
        end

        function updateComposite(app)
            app.CompositePlot.YData = app.compositeTransform + app.COMPOSITE_OFFSET;
        end


        function calculateCWT(app)
            waitbar = uiprogressdlg(app.RespirationExtractorUIFigure,'Message','Calculating CWT...');


            [app.cwtCoeffs, app.frequencies] = cwt(app.ECG(:,2),'bump',150,'VoicesPerOctive',32);
            % Calculate the frequncy limits
            app.frequencyLimits = [min(app.frequencies), max(app.frequencies)];

            % Initialize the frequency bounds
            app.bounds = app.frequencyLimits;
            % Set them on the app
            app.UpperEditField.Value = app.frequencyLimits(2);
            app.LowerEditField.Value = app.frequencyLimits(1);
            
            close(waitbar);
        end

        function calculate_iCWT(app)
            waitbar = uiprogressdlg(app.RespirationExtractorUIFigure,'Message','Reconstructing Signal...');
            app.compositeTransform = icwt(app.cwtCoeffs,[],app.frequencies,app.bounds);
            close(waitbar)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initiate hold on UI AXes
            hold(app.UIAxes,'on');
        end

        % Menu selected function: LoadCSVMenu
        function LoadCSVMenuSelected(app, event)
            app.LoadECG();
        end

        % Value changed function: UpperEditField
        function UpperEditFieldValueChanged(app, event)
            value = app.UpperEditField.Value;
            app.bounds(2) = value;
        
            if value <= app.bounds(1) | value > app.frequencyLimits(2)
                app.bounds(2) = app.frequencyLimits(2);
                app.UpperEditField.Value = app.bounds(2);
                uialert(app.RespirationExtractorUIFigure,"Value must be between " + string(app.frequencyLimits(1)) + "and " + string(app.frequencyLimits(2)),'Error!')
            else
                app.bounds(2) = value;
                app.calculate_iCWT;
                app.updateComposite;
            end
        end

        % Value changed function: LowerEditField
        function LowerEditFieldValueChanged(app, event)
            value = app.LowerEditField.Value;
            % catch
            if value > app.bounds(2) | value < app.frequencyLimits(1)
                app.bounds(1) = app.frequencyLimits(1);
                app.LowerEditField.Value = app.bounds(1);
                uialert(app.RespirationExtractorUIFigure,"Value must be between " + string(app.frequencyLimits(1)) + "and " + string(app.frequencyLimits(2)),'Error!')
            else
                app.bounds(1) = value;
                app.calculate_iCWT;
                app.updateComposite;
            end
            % end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.COMPOSITE_OFFSET = event.Value;
            % app.compositeTransform = app.compositeTransform + changingValue;
            app.updateComposite;
        end

        % Value changing function: RawECGAlphaSlider
        function RawECGAlphaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RawPlot.Color = [app.RawPlot.Color(1:3) changingValue];
        end

        % Menu selected function: ExportCSVMenu
        function ExportCSVMenuSelected(app, event)
            [file,path] = uiputfile("*.csv");
            if file
                writematrix([app.ECG(:,1); app.compositeTransform(:)],strcat(path,file));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RespirationExtractorUIFigure = uifigure('Visible', 'off');
            app.RespirationExtractorUIFigure.Name = 'Respiration Extractor';
            app.FileMenu = uimenu(app.RespirationExtractorUIFigure);
            app.FileMenu.Text = 'File...';
            app.LoadCSVMenu = uimenu(app.FileMenu);
            app.LoadCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadCSVMenuSelected, true);
            app.LoadCSVMenu.Text = 'Load CSV';
            app.ExportCSVMenu = uimenu(app.FileMenu);
            app.ExportCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportCSVMenuSelected, true);
            app.ExportCSVMenu.Text = 'Export CSV';
            app.UIAxes = uiaxes(app.RespirationExtractorUIFigure);
            xlabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [];
            app.Slider = uislider(app.RespirationExtractorUIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Enable = 'off';
            app.Slider.Value = 0.5;
            app.UpperEditFieldLabel = uilabel(app.RespirationExtractorUIFigure);
            app.UpperEditFieldLabel.Enable = 'off';
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.RespirationExtractorUIFigure, 'numeric');
            app.UpperEditField.ValueChangedFcn = createCallbackFcn(app, @UpperEditFieldValueChanged, true);
            app.UpperEditField.Enable = 'off';
            app.LowerEditFieldLabel = uilabel(app.RespirationExtractorUIFigure);
            app.LowerEditFieldLabel.Enable = 'off';
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.RespirationExtractorUIFigure, 'numeric');
            app.LowerEditField.ValueChangedFcn = createCallbackFcn(app, @LowerEditFieldValueChanged, true);
            app.LowerEditField.Enable = 'off';
            app.RawECGAlphaSliderLabel = uilabel(app.RespirationExtractorUIFigure);
            app.RawECGAlphaSliderLabel.Enable = 'off';
            app.RawECGAlphaSliderLabel.Text = 'Raw ECG Alpha';
            app.RawECGAlphaSlider = uislider(app.RespirationExtractorUIFigure);
            app.RawECGAlphaSlider.Limits = [0 1];
            app.RawECGAlphaSlider.MajorTicks = [0 1];
            app.RawECGAlphaSlider.MajorTickLabels = {'Invisible', 'Opaque'};
            app.RawECGAlphaSlider.ValueChangingFcn = createCallbackFcn(app, @RawECGAlphaSliderValueChanging, true);
            app.RawECGAlphaSlider.Enable = 'off';
            app.RawECGAlphaSliderLabel_2 = uilabel(app.RespirationExtractorUIFigure);
            app.RawECGAlphaSliderLabel_2.Text = 'Offset';
            % Show the figure after all components are created
            app.RespirationExtractorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RespirationExtractorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RespirationExtractorUIFigure)
        end
    end
end